classdef annotator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        RightPanel                 matlab.ui.container.Panel
        PlayButton                 matlab.ui.control.Button
        Slider                     matlab.ui.control.RangeSlider
        video                      matlab.ui.control.UIAxes
        CenterPanel                matlab.ui.container.Panel
        FrequencyfsEditField       matlab.ui.control.NumericEditField
        FrequencyfsEditFieldLabel  matlab.ui.control.Label
        UpdateChannelsButton       matlab.ui.control.Button
        LabelListBoxLabel_2        matlab.ui.control.Label
        Tree                       matlab.ui.container.CheckBoxTree
        DecreaseAmpButton          matlab.ui.control.Button
        IncreaseAmpButton          matlab.ui.control.Button
        seeg_wave                  matlab.ui.control.UIAxes
        LeftPanel                  matlab.ui.container.Panel
        LogLabel                   matlab.ui.control.Label
        LoadDataButton             matlab.ui.control.Button
        SessionEditField           matlab.ui.control.NumericEditField
        SessionEditFieldLabel      matlab.ui.control.Label
        SubjectEditField           matlab.ui.control.NumericEditField
        SubjectEditFieldLabel      matlab.ui.control.Label
        NextButton                 matlab.ui.control.Button
        PreviousButton             matlab.ui.control.Button
        LabelListBox               matlab.ui.control.ListBox
        LabelListBoxLabel          matlab.ui.control.Label
    end

    
    properties (Access = private)
        numCh
        totalDuration
        epochDuration
        numEpoch
        currentEpoch
        
        seegData      % seeg data
        channelIDs    % selected channels
        channelConfig
        channelNames
        chName2LocalID

        videoFile     % Video file path
        videoReader   % VideoReader object
        isPlaying     % Flag to control video playback

        labels        % annotations
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            return
        end

        % Button pushed function: LoadDataButton
        function load_data(app, event)
            tsvFile = strjoin(["./sub-", num2str(app.SubjectEditField.Value), ".tsv"], '');
            seegFile = strjoin(["./subj_", num2str(app.SubjectEditField.Value), "_ses_" num2str(app.SessionEditField.Value), ".mat"], '');
            videoSessFile = strjoin(["./sub-", num2str(app.SubjectEditField.Value), "_ses-", sprintf('%02d', app.SessionEditField.Value), ".mp4"], '');
            app.LogLabel.Text = "Loading channel config...";
            app.channelConfig = readtable(tsvFile, 'FileType', 'text', 'Delimiter', '\t','VariableNamingRule','preserve');
            app.LogLabel.Text = ["Channel config ready!" newline...
                "Loading sEEG data..."];
            seeg = load(seegFile, 'data', 'channelFlag');
            app.seegData = seeg.data;
            usedChannels = seeg.channelFlag;
            app.LogLabel.Text = ["Channel config ready!" newline...
                "sEEG data ready!" newline...
                "Loading video..."];
            app.videoReader = VideoReader(videoSessFile);
            app.isPlaying = false;
            app.LogLabel.Text = ["Channel config ready!" newline...
                "sEEG data ready!" newline...
                "Video ready!" newline...
                "Loading channels..."];
            
            % a.3 change A'1 to Ap1
            for i = 1:numel(usedChannels)
                usedChannels{i} = strrep(usedChannels{i}, '''', 'p');
            end
            usedChannels = usedChannels';

            %TODO: Update list
            brainROI = {'Precentral', 'Postcentral', 'parietal', 'temporal', 'frontal', 'Insula', 'occipital', 'Amygdala'};
            idx = false(height(app.channelConfig), 1);
            all_nodes = [];
            for i = 1:length(brainROI)
                parent = uitreenode(app.Tree, 'Text', brainROI{i});
                cortex_channel = contains(app.channelConfig.("Desikan-Killiany"), brainROI{i}, 'IgnoreCase', true);
                tids = find(cortex_channel);
                flag = false;
                for t = 1:length(tids)
                    tid = tids(t);
                    ch = app.channelConfig(tid, 1);
                    ch = string(ch{1, 1});
                    if ~isempty(find(strcmp(usedChannels, ch), 1))
                        child = uitreenode(parent, 'Text', ch);
                        all_nodes = [all_nodes, child];
                        flag = true;
                    end
                end
                if flag
                    all_nodes = [all_nodes, parent];
                end
                idx = idx | cortex_channel;
            end
            app.Tree.CheckedNodes = all_nodes;
            expand(app.Tree)
            cortexChannels = app.channelConfig(idx, [1 9]);
            clear brainROI idx i
            
            % a.4 reverse find channels in eeg file
            for i = 1:height(cortexChannels)
                idx = find(strcmp(usedChannels, cortexChannels{i,1}));
                if ~isempty(idx)
                    app.channelIDs(i) = idx;
                else
                    app.channelIDs(i) = NaN;
                    % selectedChannels(i, :) = [];
                end
            end
            app.channelIDs = app.channelIDs';
            dd = isnan(app.channelIDs);
            cortexChannels(dd,:) = []; % contain brain region
            app.channelIDs(dd) = []; % contain channel number
            clear dd
            
            
            % b.1 get channel name and time
            for i = 1:length(app.channelIDs)
                idx = app.channelIDs(i);
                cns(i) = strcat(usedChannels(idx), ': ', cortexChannels{i,2});
                keys(i) = string(usedChannels(idx));
            end
            app.chName2LocalID = dictionary(keys, 1:length(app.channelIDs));
            app.channelNames = cns;
            app.numCh = size(app.channelIDs, 1);
            app.totalDuration = size(app.seegData,1)/app.FrequencyfsEditField.Value;
            app.epochDuration = 30; % unit: second
            app.numEpoch = ceil(app.totalDuration / app.epochDuration);
            app.currentEpoch = 1;
            
            app.labels = repelem("N/A", app.numEpoch);
            app.LogLabel.Text = [...
                ['Subject ', num2str(app.SubjectEditField.Value)] ...
                ['Session ', num2str(app.SessionEditField.Value)] ...
                ['Current epoch: ', num2str(app.currentEpoch), ' / ', num2str(app.numEpoch)]...
                ['labeled: ', app.labels(app.currentEpoch)]
            ];
            
            app.update_seeg(event)
            app.Slider.Value = [(app.currentEpoch - 1) * app.epochDuration, app.currentEpoch * app.epochDuration];
            app.Slider.Limits = [max(0, (app.currentEpoch - 2) * app.epochDuration), min(app.totalDuration, (app.currentEpoch + 1) * app.epochDuration)];
            frame = readFrame(app.videoReader);
            imshow(frame, 'Parent', app.video);

        end

        % Button pushed function: UpdateChannelsButton
        function update_seeg(app, event)
             %TODO: Update list
            checkedNodes = app.Tree.CheckedNodes;
            app.numCh = 0;
            selectedChannels = [];
            cns = [];
            for i = 1:length(checkedNodes)
                if ~isempty(checkedNodes(i).Children)
                    continue;
                end
                checkedNodesText = checkedNodes(i).Text;
                lid = app.chName2LocalID(checkedNodesText);
                idx = app.channelIDs(lid);
                selectedChannels = [selectedChannels, idx];
                cns = [cns, app.channelNames(lid)];
                app.numCh = app.numCh + 1;
            end

            startIdx = (app.currentEpoch - 1) * app.epochDuration * app.FrequencyfsEditField.Value + 1;
            endIdx = startIdx + app.epochDuration * app.FrequencyfsEditField.Value - 1;
            
            plot_y = app.seegData(startIdx:endIdx, selectedChannels);
            delta = max(max(plot_y, [], 1) - min(plot_y, [], 1));
            for i = 1:app.numCh
                % Offset each channel's signal to avoid overlap
                offset(i) = (i - 1) * delta;  % Adjust the offset as needed
            end
            plot(app.seeg_wave, [startIdx:endIdx]/app.FrequencyfsEditField.Value, plot_y + flip(offset));
            % Set y-axis labels to show channel numbers
            yticks(app.seeg_wave, offset);
            yticklabels(app.seeg_wave, flip(cns));
            
            % Optimize the figure display
            axis(app.seeg_wave,'tight');
        end

        % Button pushed function: PlayButton
        function play_or_pause(app, event)
            if app.isPlaying
                app.PlayButton.Text = "Play";
                pause();
                app.isPlaying = false;
            else
                app.PlayButton.Text = "Pause";
                app.isPlaying = true;
                while hasFrame(app.videoReader) && app.isPlaying
                    frame = readFrame(app.videoReader);
                    imshow(frame, 'Parent', app.video);
                    pause(1 / app.videoReader.FrameRate);
                end
            end
        end

        % Button pushed function: PreviousButton
        function last_trial(app, event)
            app.currentEpoch = max(1, app.currentEpoch - 1);
            app.LabelListBox.Value = app.labels(app.currentEpoch);
            app.update_seeg(event)
            app.LogLabel.Text = [...
                ['Subject ', num2str(app.SubjectEditField.Value)] ...
                ['Session ', num2str(app.SessionEditField.Value)] ...
                ['Current epoch: ', num2str(app.currentEpoch), ' / ', num2str(app.numEpoch)]...
                ['labeled: ', app.labels(app.currentEpoch)]
            ];
            app.Slider.Value = [(app.currentEpoch - 1) * app.epochDuration, app.currentEpoch * app.epochDuration];
            app.Slider.Limits = [max(0, (app.currentEpoch - 2) * app.epochDuration), min(app.totalDuration, (app.currentEpoch + 1) * app.epochDuration)];
        end

        % Button pushed function: NextButton
        function next_trial(app, event)
            app.currentEpoch = min(app.numEpoch, app.currentEpoch + 1);
            app.LabelListBox.Value = app.labels(app.currentEpoch);
            app.update_seeg(event)
            app.LogLabel.Text = [...
                ['Subject ', num2str(app.SubjectEditField.Value)] ...
                ['Session ', num2str(app.SessionEditField.Value)] ...
                ['Current epoch: ', num2str(app.currentEpoch), ' / ', num2str(app.numEpoch)]...
                ['labeled: ', app.labels(app.currentEpoch)]
            ];
        end

        % Value changed function: LabelListBox
        function annotate(app, event)
            value = app.LabelListBox.Value;
            app.labels(app.currentEpoch) = value;
            app.LogLabel.Text = [...
                ['Subject ', num2str(app.SubjectEditField.Value)] ...
                ['Session ', num2str(app.SessionEditField.Value)] ...
                ['Current epoch: ', num2str(app.currentEpoch), ' / ', num2str(app.numEpoch)]...
                ['labeled: ', app.labels(app.currentEpoch)]
            ];
            app.Slider.Value = [(app.currentEpoch - 1) * app.epochDuration, app.currentEpoch * app.epochDuration];
            app.Slider.Limits = [max(0, (app.currentEpoch - 2) * app.epochDuration), min(app.totalDuration, (app.currentEpoch + 1) * app.epochDuration)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.LabelListBoxLabel = uilabel(app.LeftPanel);
            app.LabelListBox = uilistbox(app.LeftPanel);
            app.LabelListBox.Items = {'N/A', 'N1', 'N2', 'N3', 'REM', 'Wake', 'Other'};
            app.LabelListBox.ValueChangedFcn = createCallbackFcn(app, @annotate, true);
            app.LabelListBox.Value = 'N/A';
            app.PreviousButton = uibutton(app.LeftPanel, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @last_trial, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.LeftPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @next_trial, true);
            app.NextButton.Text = 'Next';
            app.SubjectEditFieldLabel = uilabel(app.LeftPanel);
            app.SubjectEditFieldLabel.Text = 'Subject';
            app.SubjectEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SubjectEditField.Value = 21;
            app.SessionEditFieldLabel = uilabel(app.LeftPanel);
            app.SessionEditFieldLabel.Text = 'Session';
            app.SessionEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SessionEditField.Value = 2;
            app.LoadDataButton = uibutton(app.LeftPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @load_data, true);
            app.LoadDataButton.Text = 'Load Data';
            app.LogLabel = uilabel(app.LeftPanel);
            app.LogLabel.Text = 'Log';
            app.CenterPanel = uipanel(app.UIFigure);
            app.seeg_wave = uiaxes(app.CenterPanel);
            title(app.seeg_wave, 'sEEG')
            xlabel(app.seeg_wave, 'Time')
            ylabel(app.seeg_wave, 'Y')
            zlabel(app.seeg_wave, 'Z')
            app.IncreaseAmpButton = uibutton(app.CenterPanel, 'push');
            app.IncreaseAmpButton.Text = 'Increase Amp';
            app.DecreaseAmpButton = uibutton(app.CenterPanel, 'push');
            app.DecreaseAmpButton.Text = 'Decrease Amp';
            app.Tree = uitree(app.CenterPanel, 'checkbox');
            app.LabelListBoxLabel_2 = uilabel(app.CenterPanel);
            app.LabelListBoxLabel_2.Text = 'Channels';
            app.UpdateChannelsButton = uibutton(app.CenterPanel, 'push');
            app.UpdateChannelsButton.ButtonPushedFcn = createCallbackFcn(app, @update_seeg, true);
            app.UpdateChannelsButton.Text = 'Update Channels';
            app.FrequencyfsEditFieldLabel = uilabel(app.CenterPanel);
            app.FrequencyfsEditFieldLabel.Text = 'Frequency (fs)';
            app.FrequencyfsEditField = uieditfield(app.CenterPanel, 'numeric');
            app.FrequencyfsEditField.Value = 250;
            app.RightPanel = uipanel(app.UIFigure);
            app.video = uiaxes(app.RightPanel);
            title(app.video, 'Video')
            app.video.XTick = [];
            app.video.YTick = [];
            app.Slider = uislider(app.RightPanel, 'range');
            app.PlayButton = uibutton(app.RightPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @play_or_pause, true);
            app.PlayButton.Text = 'Play';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = annotator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end